classdef BraidMeasureApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Tab1                       matlab.ui.container.Tab
        Panel                      matlab.ui.container.Panel
        UploadButton               matlab.ui.control.Button
        Button2                    matlab.ui.control.Button
        Panel2                     matlab.ui.container.Panel
        Panel3                     matlab.ui.container.Panel
        Label                      matlab.ui.control.Label
        AngleDisplayTextArea       matlab.ui.control.TextArea
        StatusTextArea             matlab.ui.control.TextArea
        CropandUnwrapPanel         matlab.ui.container.Panel
        CropImageCheckBox          matlab.ui.control.CheckBox
        FilterParametersPanel      matlab.ui.container.Panel
        MedWindowSpinnerLabel      matlab.ui.control.Label
        MedWindowSpinner           matlab.ui.control.Spinner
        AveWindowSizeSpinnerLabel  matlab.ui.control.Label
        AveWindowSizeSpinner       matlab.ui.control.Spinner
        BackgroundSpinnerLabel     matlab.ui.control.Label
        BackgroundSpinner          matlab.ui.control.Spinner
        Button                     matlab.ui.control.StateButton
        AlphadegSpinnerLabel       matlab.ui.control.Label
        AlphadegSpinner            matlab.ui.control.Spinner
        Tab2                       matlab.ui.container.Tab
        StartCameraButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        uploadedImage; % Description
        imageSizeX % Description
        imageSizeY % Description
        
        imAxes % Description
        medianWindowSize % Description
        averageFilterWindowSize % Description
        backgroundSubtractValue % Description
        cropImageChkBoxVal = 0;% Description
        filterImage % Description
        alphaVal % Description
        unwrapImage % Description
        cropImage % Description
        leftBoundary % Description
        rightBoundary % Description
        centerLine % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
        end

        % Value changing function: AveWindowSizeSpinner
        function AveWindowSizeSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.averageFilterWindowSize = changingValue;
            
            %process image
            
            app.filterImage = adaptivethreshold(app.uploadedImage, app.averageFilterWindowSize, app.backgroundSubtractValue, 0, app.medianWindowSize);
            [ app.leftBoundary, app.rightBoundary, app.centerLine, braidWidth ] = boundaryV2( app.filterImage );
            imshow(app.filterImage, 'Parent', app.imAxes)
            line(app.imAxes, [app.leftBoundary, app.leftBoundary], [0, app.imageSizeY], 'color', 'r', 'linewidth', 2)
            line(app.imAxes, [app.rightBoundary, app.rightBoundary], [0, app.imageSizeY], 'color', 'r', 'linewidth', 2)
        end

        % Value changing function: BackgroundSpinner
        function BackgroundSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.backgroundSubtractValue = changingValue;
            
            %process image
            
            app.filterImage = adaptivethreshold(app.uploadedImage, app.averageFilterWindowSize, app.backgroundSubtractValue, 0, app.medianWindowSize);
            [ app.leftBoundary, app.rightBoundary, app.centerLine, braidWidth ] = boundaryV2( app.filterImage );
            imshow(app.filterImage, 'Parent', app.imAxes)
            line(app.imAxes, [app.leftBoundary, app.leftBoundary], [0, app.imageSizeY], 'color', 'r', 'linewidth', 2)
            line(app.imAxes, [app.rightBoundary, app.rightBoundary], [0, app.imageSizeY], 'color', 'r', 'linewidth', 2)
        end

        % Value changed function: CropImageCheckBox
        function CropImageCheckBoxValueChanged(app, event)
            value = app.CropImageCheckBox.Value;
            app.cropImageChkBoxVal = value;
            
            if value == 1
                
                app.MedWindowSpinnerLabel.Enable = 'on';
                app.MedWindowSpinner.Enable = 'on';
                
                app.AveWindowSizeSpinnerLabel.Enable = 'on';
                app.AveWindowSizeSpinner.Enable = 'on';
                
                app.BackgroundSpinnerLabel.Enable = 'on';
                app.BackgroundSpinner.Enable = 'on';
                
                %get values from spinner boxes
                app.averageFilterWindowSize = app.AveWindowSizeSpinner.Value;
                app.backgroundSubtractValue =   app.BackgroundSpinner.Value;
                app.medianWindowSize = app.MedWindowSpinner.Value;
                app.alphaVal = app.AlphadegSpinner.Value;
            else
                
                app.MedWindowSpinnerLabel.Enable = 'off';
                app.MedWindowSpinner.Enable = 'off';
                
                app.AveWindowSizeSpinnerLabel.Enable = 'off';
                app.AveWindowSizeSpinner.Enable = 'off';
                
                app.BackgroundSpinnerLabel.Enable = 'off';
                app.BackgroundSpinner.Enable = 'off';
            end
        end

        % Button pushed function: Button2
        function MeasureAngle(app, event)
            app.AngleDisplayTextArea.Value = 'Please Wait';
            
            if app.cropImageChkBoxVal == 0
                app.AngleDisplayTextArea.Value = 'Please Wait';
                [ imageFrequencySpectrum ] = frequencytransform( app.uploadedImage, [1500 1500] );
                
            elseif app.cropImageChkBoxVal == 1
                app.AngleDisplayTextArea.Value = 'Please Wait';
                %TODO add process crop image
                [ app.unwrapImage, app.cropImage] = surfaceunwrapfunction( app.uploadedImage, app.leftBoundary, app.rightBoundary, app.centerLine, app.alphaVal );
                [ imageFrequencySpectrum ] = frequencytransform( app.cropImage, [1500 1500] );
                
            else
                
            end
            
            [intensity, directionVector, angle, thetaRight, thetaLeft, indRight, indLeft, braidAngle, ~, ~, averageAngleDistribution, sortedCirclePoints] = braidanglescanBresenham_v2(imageFrequencySpectrum, 0);
            
            %Indludes distribution
            %braidAngleResultString = strcat(sprintf('%0.2f',  braidAngle), char(176), char(177), sprintf('%0.2f', averageAngleDistribution), char(176));
                        
            braidAngleResultString = strcat(sprintf('%0.2f',  braidAngle), char(176), char(177));
            
            app.AngleDisplayTextArea.Value = braidAngleResultString;
            
            %             %Write angle scan outputs to handle structure
            %             handles.sortedCirclePoints = sortedCirclePoints;
            %             handles.intensity = intensity;
            %             handles.angle = angle;
            %             handles.imageFrequencySpectrum = imageFrequencySpectrum;
            %             handles.directionVector = directionVector;
            %             handles.indRight = indRight;
            %             handles.indLeft = indLeft;
            
            %Write angle scan outputs to workspace
            assignin('base', 'thetaRight', thetaRight)
            assignin('base', 'thetaLeft', thetaLeft)
            %assignin('base', 'windowImage', windowImage)
        end

        % Value changing function: MedWindowSpinner
        function MedWindowSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.medianWindowSize = changingValue;
            
            app.filterImage = adaptivethreshold(app.uploadedImage, app.averageFilterWindowSize, app.backgroundSubtractValue, 0, app.medianWindowSize);
            [ app.leftBoundary, app.rightBoundary, app.centerLine, braidWidth ] = boundaryV2( app.filterImage );
            imshow(app.filterImage, 'Parent', app.imAxes)
            line(app.imAxes, [app.leftBoundary, app.leftBoundary], [0, app.imageSizeY], 'color', 'r', 'linewidth', 2)
            line(app.imAxes, [app.rightBoundary, app.rightBoundary], [0, app.imageSizeY], 'color', 'r', 'linewidth', 2)
            
            %process image
            
        end

        % Button pushed function: StartCameraButton
        function StartCameraButton_Callback(app, event)
            [file, path] = uiputfile({'.log'});
            logFilePath = strcat(path, file);
            logFileName = strtok(file, '.'); %separate file name from file extension
            realVideoBraid(logFilePath, logFileName);
            
        end

        % Button pushed function: UploadButton
        function UploadButtonButtonPushed(app, event)
            [imageFileName, imagePathname, ~] = uigetfile({'*.jpg; *.tif; *.tiff; *.bmp; *.gif; *.jpeg',...
                'Image Files (*.jpg, *.tif, *.bmp, *.gif, *jpeg)'}); %Defining all image files
            if imageFileName == 0
                msgbox('Image was not selected')
            else
                app.StatusTextArea.Value = imageFileName;
                
                %reading in selected image file as variable
                app.uploadedImage = imread(strcat(imagePathname,imageFileName));
                
                %Size of image
                [app.imageSizeY, app.imageSizeX] = size(app.uploadedImage);
                
                fig = uifigure('name', 'Uploaded Image');
                app.imAxes = uiaxes(fig);
                imshow(app.uploadedImage, 'Parent', app.imAxes)
                
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Manual Upload';
            app.Panel = uipanel(app.Tab1);
            app.Panel.Title = 'Upload Image';
            app.UploadButton = uibutton(app.Panel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonButtonPushed, true);
            app.UploadButton.Text = 'Upload Image';
            app.Button2 = uibutton(app.Panel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @MeasureAngle, true);
            app.Button2.Text = 'Measure Angle';
            app.Panel2 = uipanel(app.Panel);
            app.Panel2.Title = ' Display Options';
            app.Panel3 = uipanel(app.Panel);
            app.Panel3.Title = 'Braid Angle';
            app.Label = uilabel(app.Panel3);
            app.Label.Text = '';
            app.AngleDisplayTextArea = uitextarea(app.Panel3);
            app.AngleDisplayTextArea.Editable = 'off';
            app.StatusTextArea = uitextarea(app.Panel);
            app.StatusTextArea.Editable = 'off';
            app.CropandUnwrapPanel = uipanel(app.Tab1);
            app.CropandUnwrapPanel.Title = 'Crop and Unwrap';
            app.CropImageCheckBox = uicheckbox(app.CropandUnwrapPanel);
            app.CropImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @CropImageCheckBoxValueChanged, true);
            app.CropImageCheckBox.Text = 'Crop Image';
            app.FilterParametersPanel = uipanel(app.CropandUnwrapPanel);
            app.FilterParametersPanel.Title = 'Filter Parameters';
            app.MedWindowSpinnerLabel = uilabel(app.FilterParametersPanel);
            app.MedWindowSpinnerLabel.Enable = 'off';
            app.MedWindowSpinnerLabel.Text = 'Med. Window';
            app.MedWindowSpinner = uispinner(app.FilterParametersPanel);
            app.MedWindowSpinner.Step = 2;
            app.MedWindowSpinner.ValueChangingFcn = createCallbackFcn(app, @MedWindowSpinnerValueChanging, true);
            app.MedWindowSpinner.Limits = [1 Inf];
            app.MedWindowSpinner.Enable = 'off';
            app.MedWindowSpinner.Value = 7;
            app.AveWindowSizeSpinnerLabel = uilabel(app.FilterParametersPanel);
            app.AveWindowSizeSpinnerLabel.Enable = 'off';
            app.AveWindowSizeSpinnerLabel.Text = 'Ave. Window Size';
            app.AveWindowSizeSpinner = uispinner(app.FilterParametersPanel);
            app.AveWindowSizeSpinner.ValueChangingFcn = createCallbackFcn(app, @AveWindowSizeSpinnerValueChanging, true);
            app.AveWindowSizeSpinner.Limits = [0 Inf];
            app.AveWindowSizeSpinner.Enable = 'off';
            app.AveWindowSizeSpinner.Value = 30;
            app.BackgroundSpinnerLabel = uilabel(app.FilterParametersPanel);
            app.BackgroundSpinnerLabel.Enable = 'off';
            app.BackgroundSpinnerLabel.Text = 'Background';
            app.BackgroundSpinner = uispinner(app.FilterParametersPanel);
            app.BackgroundSpinner.ValueChangingFcn = createCallbackFcn(app, @BackgroundSpinnerValueChanging, true);
            app.BackgroundSpinner.Limits = [0 Inf];
            app.BackgroundSpinner.Enable = 'off';
            app.Button = uibutton(app.CropandUnwrapPanel, 'state');
            app.Button.Text = '';
            app.AlphadegSpinnerLabel = uilabel(app.CropandUnwrapPanel);
            app.AlphadegSpinnerLabel.Text = 'Alpha [deg]';
            app.AlphadegSpinner = uispinner(app.CropandUnwrapPanel);
            app.AlphadegSpinner.Limits = [0 90];
            app.AlphadegSpinner.Value = 70;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Live Camera Collection';
            app.StartCameraButton = uibutton(app.Tab2, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButton_Callback, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BraidMeasureApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end